classdef problema2_Ladino_Martinez < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        GraficarButton          matlab.ui.control.Button
        IngresenEditFieldLabel  matlab.ui.control.Label
        IngresenEditField       matlab.ui.control.NumericEditField
        IngresecEditFieldLabel  matlab.ui.control.Label
        IngresecEditField       matlab.ui.control.NumericEditField
        EspereunossegundoselprogramaestapensandoLabel  matlab.ui.control.Label
        centre1523Label         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            app.EspereunossegundoselprogramaestapensandoLabel.Visible = true;
            pause(0.01);
            x=-2:0.002:1; %porción del eje real tomada
            y=-1:0.002:1; %porción del eje imaginario tomada
            temp = int8(app.IngresenEditField.Value);
            n = double(temp);
            c = app.IngresecEditField.Value;
            itM = 100; %"infinito"
            [X,Y] = meshgrid(x,y); 
            A = X + 1j*Y; %la matriz A es la retícula de puntos que vamos a pintar
            D = zeros(size(A)); 
            %% Averiguamos si los números están en el conjunto
            for i = 1:length(y)
                for j = 1:length(x)
                    z0 = A(i,j); %tomamos el número que vamos a analizar en el bucle de abajo
                    z = 0; %inicializamos la variable
                    k = 0; %inicializamos el contador de iteraciones
                    while abs(z) < 2 && k < itM
                        z = z^n + z0 + c;
                        k = k + 1; 
                    end
                    D(i,j) = k; %D(i,j) es el número de iteraciones de A(i,j)
                end  
            end
            %% Coloreamos la matriz D
            [p,q] = size(A);
            app.UIAxes.XLim = [0,q];
            app.UIAxes.YLim = [0,p];
            imagesc(app.UIAxes,D)
            app.EspereunossegundoselprogramaestapensandoLabel.Visible = false;

            %colormap hot
            %axis equal %ejes proporcionados
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Set de Julia')
            xlabel(app.UIAxes, '__Eje X Re(f(z))__')
            ylabel(app.UIAxes, '__Eje Y Im(f(z))__ ')
            app.UIAxes.XLim = [0 1500];
            app.UIAxes.YLim = [0 1000];
            app.UIAxes.Tag = 'Graff';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Tag = 'Graficar';
            app.GraficarButton.Text = 'Graficar';
            app.IngresenEditFieldLabel = uilabel(app.UIFigure);
            app.IngresenEditFieldLabel.Text = {'Ingrese n:'; ''};
            app.IngresenEditField = uieditfield(app.UIFigure, 'numeric');
            app.IngresenEditField.Tag = 'nValue';
            app.IngresecEditFieldLabel = uilabel(app.UIFigure);
            app.IngresecEditFieldLabel.Text = 'Ingrese c:';
            app.IngresecEditField = uieditfield(app.UIFigure, 'numeric');
            app.IngresecEditField.Tag = 'cValue';
            app.IngresecEditField.Value = 0.5;
            app.EspereunossegundoselprogramaestapensandoLabel = uilabel(app.UIFigure);
            app.EspereunossegundoselprogramaestapensandoLabel.Tag = 'WaitLabel';
            app.EspereunossegundoselprogramaestapensandoLabel.Visible = 'off';
            app.EspereunossegundoselprogramaestapensandoLabel.Text = {'Espere unos'; 'segundos '; 'el programa '; 'esta pensando ...'};
            app.centre1523Label = uilabel(app.UIFigure);
            app.centre1523Label.Text = 'c entre (-1.5 , 2.3)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = problema2_Ladino_Martinez

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end